classdef Reflectivityapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ReflectivitydataTab             matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        ReflectivityDataEditFieldLabel  matlab.ui.control.Label
        ReflectivityDataEditField       matlab.ui.control.EditField
        SelectfolderButton              matlab.ui.control.Button
        OutputfolderButton              matlab.ui.control.Button
        OutputdataTextArea              matlab.ui.control.TextArea
        OutputfileButton                matlab.ui.control.Button
        LoadButton_4                    matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        SelectfileButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        UITable_2                       matlab.ui.control.Table
        MLButton                        matlab.ui.control.Button
        AbsButton                       matlab.ui.control.Button
        RmButton                        matlab.ui.control.Button
        MultidimensionalregressionTab   matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        FilmparametersTab               matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        N_MLEditFieldLabel              matlab.ui.control.Label
        N_MLEditField                   matlab.ui.control.NumericEditField
        LayerButton                     matlab.ui.control.Button
        RmButton_3                      matlab.ui.control.Button
        LoadButton_3                    matlab.ui.control.Button
        DataselectorTab                 matlab.ui.container.Tab
        UITable2_3                      matlab.ui.control.Table
        LoadButton_2                    matlab.ui.control.Button
        UITable5                        matlab.ui.control.Table
        PolarizationEditFieldLabel      matlab.ui.control.Label
        PolarizationEditField           matlab.ui.control.NumericEditField
        RegressionsettingsTab           matlab.ui.container.Tab
        WeightabsorberCheckBox          matlab.ui.control.CheckBox
        RoughnessamplitudecorrectionCheckBox  matlab.ui.control.CheckBox
        SubstrateRMSnmEditFieldLabel    matlab.ui.control.Label
        SubstrateRMSnmEditField         matlab.ui.control.NumericEditField
        UITable6                        matlab.ui.control.Table
        AddstepButton                   matlab.ui.control.Button
        RemovestepButton                matlab.ui.control.Button
        IncludesurfaceroughnessCheckBox  matlab.ui.control.CheckBox
        FittedparametersTab             matlab.ui.container.Tab
        UITable2_4                      matlab.ui.control.Table
        UIAxes2                         matlab.ui.control.UIAxes
        RunButton                       matlab.ui.control.Button
        UIAxes2_2                       matlab.ui.control.UIAxes
        TextArea                        matlab.ui.control.TextArea
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        ReflectanceanalysisTab          matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        FitTab                          matlab.ui.container.Tab
        UIAxes5_6                       matlab.ui.control.UIAxes
        UIAxes5_7                       matlab.ui.control.UIAxes
        UIAxes5_8                       matlab.ui.control.UIAxes
        UIAxes5_9                       matlab.ui.control.UIAxes
        UIAxes5_10                      matlab.ui.control.UIAxes
        DecompositionTab                matlab.ui.container.Tab
        UIAxes5_11                      matlab.ui.control.UIAxes
        UIAxes5_12                      matlab.ui.control.UIAxes
        UIAxes5_13                      matlab.ui.control.UIAxes
        UIAxes5_14                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function results = updateDataSelector(app)
            if exist(app.OutputdataTextArea.Value{1},'file')
                tmp = load(app.OutputdataTextArea.Value{1});
                if isfield(tmp,'data_cell')
                    d = tmp.data_cell;
                    type = cellfun(@(x) x.type,d,'UniformOutput',false);
                    source = cellfun(@(x) x.source,d,'UniformOutput',false);
                    d = app.UITable2_3.Data;
                    if isempty(d)
                        d = cell(0,2);
                    end
                    is_true = find(cell2mat(d(:,2)));
                    
                    has_ML = any(strcmp('Multilayer',d(is_true,1)));
                    if has_ML
                        ML_ind = find(strcmp('Multilayer',d(is_true,1)));
                    end
                    has_Abs = any(strcmp('Absorber',d(is_true,1)));
                    if has_Abs
                        Abs_ind = find(strcmp('Absorber',d(is_true,1)));
                    end
                    
                    app.UITable2_3.Data = [type,num2cell(false(size(type,1),1)),source];
                    
                    d = app.UITable2_3.Data;
                    if isempty(d)
                        d = cell(0,2);
                    end
                    
                    if has_ML
                        app.UITable2_3.Data(ML_ind,2) = {true};
                    else
                        if any(strcmp(d(:,1),'Multilayer'))
                            app.UITable2_3.Data(find(strcmp(d(:,1),'Multilayer'),1),2) = {true};
                        end
                    end
                    if has_Abs
                        app.UITable2_3.Data(Abs_ind,2) = {true};
                    else
                        if any(strcmp(d(:,1),'Absorber'))
                            app.UITable2_3.Data(find(strcmp(d(:,1),'Absorber'),1),2) = {true};
                        end
                    end
                end
            end
        end
        
        function results = updateReflectanceData(app,tf)
            if exist(app.OutputdataTextArea.Value{1},'file')
                tmp = load(app.OutputdataTextArea.Value{1},'model');
%                 tmp = load(app.OutputdataTextArea.Value{1},'model_cell');
            else
                return
            end
            
%             if isfield(tmp,'model_cell')
%                 model_cell = tmp.model_cell;
%             else
%                 return
%             end
%             
%             if nargin < 2
%                 tf = false(length(model_cell),1);
%                 tf(end) = true;
%             end
%             
%             app.UITable2_5.Data = [num2cell(tf(:)), cellfun(@(x) x.source_file,model_cell(:),'UniformOutput',false)];
            
            updateReflectancePlots(app)
            
            drawnow()
        end
        
        function results = updateReflectancePlots(app)
            if exist(app.OutputdataTextArea.Value{1},'file')
                tmp = load(app.OutputdataTextArea.Value{1},'model');
%                 tmp = load(app.OutputdataTextArea.Value{1},'model_cell');
            else
                return
            end
            
%             d = app.UITable2_5.Data;
%             model = tmp.model_cell{find(cell2mat(d(:,1)),1)};
            if isfield(tmp,'model')
                model = tmp.model;
            else
                return
            end
            
            [r1,r2,r_ML,r_abs,t_abs,r_etch,t_unetch] = model.fFres_pol(model.x,'s');
            
            r = r1./r2;
            
            kz = 2*pi./model.lambdaTheta(:,1).*cosd(model.lambdaTheta(:,2));
            
            [kzsort,inds] = sort(kz);
            
            data = model.data;
            pred = [r1,r2];
            
            %% Plot absorber amplitude
            tbl = [data(:,1),abs(pred(:,1))];
            tbl = tbl(inds,:);
            
            plot(app.UIAxes5_6,kzsort,tbl)
            app.UIAxes5_6.XLim = kzsort([1 end]);
            title(app.UIAxes5_6,'$|r_{Abs}|$')
            ylabel(app.UIAxes5_6,'$|r|$')
            xlabel(app.UIAxes5_6,'$k_z$')
            legend(app.UIAxes5_6,{'Data','Model'})
            grid(app.UIAxes5_6,'on')
            app.UIAxes5_6.XLim = [min(kzsort) max(kzsort)];
            
            %% Plot multilayer amplitude
            tbl = [data(:,2),abs(pred(:,2))];
            tbl = tbl(inds,:);
            
            plot(app.UIAxes5_7,kzsort,tbl)
            app.UIAxes5_7.XLim = kzsort([1 end]);
            title(app.UIAxes5_7,'$|r_{ML}|$')
            ylabel(app.UIAxes5_7,'$|r|$')
            xlabel(app.UIAxes5_7,'$k_z$')
            legend(app.UIAxes5_7,{'Data','Model'})
            grid(app.UIAxes5_7,'on')
            app.UIAxes5_7.XLim = [min(kzsort) max(kzsort)];
            
            %% Plot correlation
            tbl1 = abs(pred);
            tbl2 = data;
            
            tbl1 = tbl1(inds,:);
            tbl2 = tbl2(inds,:);
            plot(app.UIAxes5_8,tbl1,tbl2,'.')
            
            title(app.UIAxes5_8,'Correlation')
            ylabel(app.UIAxes5_8,'Data')
            xlabel(app.UIAxes5_8,'Model')
            legend(app.UIAxes5_8,{'Absorber','Multilayer'},'Location','northwest')
            grid(app.UIAxes5_8,'on')
            
            %% Plot multilayer and absorber phase
            tbl = rad2deg(unwrap(angle(pred(inds,:))));
            kz0 = 2*pi/13.5*cosd(6);
            [~,i0] = min(abs(kzsort-kz0));
            tbl = tbl - 360*floor(tbl(i0,:)/360);
            
            plot(app.UIAxes5_9,kzsort,tbl)
            title(app.UIAxes5_9,'$\phi$')
            ylabel(app.UIAxes5_9,'$\phi$ [degrees]')
            xlabel(app.UIAxes5_9,'$k_z$')
            legend(app.UIAxes5_9,{'Absorber','Multilayer'})
            app.UIAxes5_9.YTick = 360*-3:90:360*3;
            app.UIAxes5_9.YLim = 360*1.5*[-1 1];
            app.UIAxes5_9.XLim = [min(kzsort) max(kzsort)];
            grid(app.UIAxes5_9,'on')
            
            %% Plot multilayer and absorber phase
            tbl = rad2deg(unwrap(angle(pred(inds,:))));
            tbl = tbl(:,1) - tbl(:,2);
            kz0 = 2*pi/13.5*cosd(6);
            [~,i0] = min(abs(kzsort-kz0));
            tbl = tbl - 360*floor(tbl(i0,:)/360);
            
            plot(app.UIAxes5_10,kzsort,tbl)
            title(app.UIAxes5_10,'$\Delta\phi$, Absorber minus multilayer')
            ylabel(app.UIAxes5_10,'$\Delta\phi$ [degrees]')
            xlabel(app.UIAxes5_10,'$k_z$')
            app.UIAxes5_10.XLim = [min(kzsort) max(kzsort)];
            app.UIAxes5_10.YTick = 360*-3:30:360*3;
%             app.UIAxes5_10.YLim = 360*[-0.25 1.25];
            grid(app.UIAxes5_10,'on')
            
            %% Reflectance decomposition 
            r_strs = {'$r_{Abs}$','$r_{ML}$','$r_{rel}$','$r_{ML0}$','$r_{Abs0}$','$t_{Abs0}^2$','$r_{etch0}$','$t_{etch0}^2$'};
%             r_fit_mat = zeros(length(kz),length(r_strs));
%             [r1,r2,r_ML,r_abs,t_abs,r_etch,t_unetch] = fFres(x_fit_mat(:,iDat));
            r = r1./r2;
            r_fit_mat = [r1,r2,r,r_ML,r_abs,t_abs.^2,r_etch,t_unetch.^2];
            rsort = r_fit_mat(inds,:);
            
            %% Absorber decomposition
            strs = [r_strs(1);{[r_strs{2}(1:end-1) r_strs{6}(2:end)]};r_strs(5)];
            
            y = [rsort(:,1),rsort(:,4).*rsort(:,6),rsort(:,5)];
%             y = y./exp(1i*angle(y(:,2)));
            
            tbl = abs(y);
            semilogy(app.UIAxes5_11,kzsort,tbl)
            legend(app.UIAxes5_11,strs,'Location','south')
            app.UIAxes5_11.YLim = [0.001,0.5];
            app.UIAxes5_11.XLim = [min(kzsort) max(kzsort)];
            grid(app.UIAxes5_11,'on')
            xlabel(app.UIAxes5_11,'$k_z$')
            ylabel(app.UIAxes5_11,'$|r|$')
            title(app.UIAxes5_11,'$|r_{Abs}|$ decomposition')
            
            tbl = rad2deg(angle(y));
            plot(app.UIAxes5_12,kzsort,tbl)
            legend(app.UIAxes5_12,strs,'Location','south')
            grid(app.UIAxes5_12,'on')
            xlabel(app.UIAxes5_12,'$k_z$')
            ylabel(app.UIAxes5_12,'$\phi$')
            app.UIAxes5_12.YLim = 360*0.5*[-1 1];
            app.UIAxes5_12.YTick = 360*-3:30:360*3;
            app.UIAxes5_12.XLim = [min(kzsort) max(kzsort)];
            title(app.UIAxes5_12,'$\phi_{Abs}$ decomposition')
            
            %% Multilayer decomposition
            y = [rsort(:,2),rsort(:,4).*rsort(:,8),rsort(:,7)];
%             y = y./exp(1i*angle(y(:,2)));
            
            strs = [r_strs(2);{[r_strs{4}(1:end-1) r_strs{8}(2:end)]};r_strs(7)];
            
            tbl = abs(y);
            semilogy(app.UIAxes5_13,kzsort,tbl)
            legend(app.UIAxes5_13,strs,'Location','south')
            app.UIAxes5_13.YLim = [0.01,1];
            app.UIAxes5_13.XLim = [min(kzsort) max(kzsort)];
            grid(app.UIAxes5_13,'on')
            xlabel(app.UIAxes5_13,'$k_z$')
            ylabel(app.UIAxes5_13,'$|r|$')
            title(app.UIAxes5_13,'$|r_{ML}|$ decomposition')
            
            tbl = rad2deg(angle(y));
            plot(app.UIAxes5_14,kzsort,tbl)
            legend(app.UIAxes5_14,strs,'Location','south')
            grid(app.UIAxes5_14,'on')
            xlabel(app.UIAxes5_14,'$k_z$')
            ylabel(app.UIAxes5_14,'$\phi$')
            app.UIAxes5_14.YLim = 360*0.5*[-1 1];
            app.UIAxes5_14.YTick = 360*-3:30:360*3;
            app.UIAxes5_14.XLim = [min(kzsort) max(kzsort)];
            
            title(app.UIAxes5_14,'$\phi_{ML}$ decomposition')
        end
        
        function results = saveappfig(app,str)
            if true
                return
            end
            
            pause(0.5)
            %% Save image of app
            s = app.OutputdataTextArea.Value{1};
            [FILEPATH,NAME,EXT] = fileparts(s);
            
            s = [FILEPATH '\' str '.png'];
            
            
            % Take screen capture
            robot = java.awt.Robot();
            set(0,'units','pixels')  
            pos_screen = get(0,'screensize');
            pos = [1 1 4000 2000];
            pos_fig = app.UIFigure.Position;
            rect = java.awt.Rectangle(pos(1),pos(2),pos(3),pos(4));
            cap = robot.createScreenCapture(rect);
            % Convert to an RGB image
            rgb = typecast(cap.getRGB(0,0,cap.getWidth,cap.getHeight,[],0,cap.getWidth),'uint8');
            imgData = zeros(cap.getHeight,cap.getWidth,3,'uint8');
            imgData(:,:,1) = reshape(rgb(3:4:end),cap.getWidth,[])';
            imgData(:,:,2) = reshape(rgb(2:4:end),cap.getWidth,[])';
            imgData(:,:,3) = reshape(rgb(1:4:end),cap.getWidth,[])';
            
            imgData(:,sum(imgData,[1 3])==0,:) = [];
            imgData(sum(imgData,[2 3])==0,:,:) = [];
            
            scalex = size(imgData,2)/pos_screen(3);
            scaley = size(imgData,1)/pos_screen(4);
            scale = mean([scalex,scaley]);
            
            x0 = round(pos_fig(1)*scale) - 10;
            y0 = round(pos_fig(2)*scale) - 10;
            
            wx = round(pos_fig(3)*scale) + 20;
            wy = round(pos_fig(4)*scale) + 20;
            
            imgData = imgData(y0+(1:wy),x0+(1:wx),:);
            
            % Show or save to file
            imshow(imgData)
            imwrite(imgData,s)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(groot,'defaultAxesFontSize',24)
            set(groot,'defaultUITableFontSize',24)
            set(groot,'defaulttextInterpreter','latex')
            set(groot,'defaultColorbarTickLabelInterpreter','latex')
            set(groot,'defaultAxesTickLabelInterpreter','latex')
            set(groot,'defaultLegendInterpreter','latex')
            set(groot,'defaultLineLineWidth',3)
            set(groot,'defaultLineMarkerSize',15)
            
            app.UIFigure.Position(2) = 50;
            app.UITable.Data = [12.5,14.5,0.02;2,8,1];
            
            app.UITable5.Data = [12.5,14.5;2,8];
            
            updateReflectanceData(app)
            
            %% Populate film model table
            s = app.OutputdataTextArea.Value{1};
            [FILEPATH,NAME,EXT] = fileparts(s);
            filename = 'Film model.xlsx';
            d = importdata([FILEPATH '\' filename]);
            n = d.data;
            d = d.textdata(2:end,:);
            ii = 1:size(d,1);            
            app.UITable2.Data = d(ii,:);
            app.UITable2.Data(:,4:6) = num2cell(n(ii,1:3));
            %% Populate data selector table
            updateDataSelector(app)
            %% Populate optimization selector table
%                                Cycles,Iter,Shrink,Alpha,Step, Prior, Opt thick,Opt rough,Opt comp,Opt ML,Opt abs,Opt etch
            app.UITable6.Data = [10,    10,  0.9,   0.99,  0.1,  0.2,  true,     false,    true,    true,  false,  false; % ML only, opt thickness and composition
                                 10,    10,  0.9,   0.99,  0.1,  0.2,  false,    false,    true,    false, true,   false; % Absorber only, opt composition
                                 10,    10,  0.9,   0.99,  0.1,  0.2,  true,     false,    false,   false, true,   false; % Absorber only, opt thickness
                                 10,    10,  0.9,   0.99,  0.1,  0.2,  true,     false,    false,   true,  true,   true; % Absorber+ML+Etch, opt thickness;
                                 40,    10,  0.9,   0.99,  0.05, 0.1, true,     true,     true,    true,  true,   true]; % Opt all

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            s = app.ReflectivityDataEditField.Value;
            dataFile = s;
            
            %% Import data file(s)
            dat = importdata(dataFile);
            R = dat.data(:,2:end);
            
            d = app.UITable.Data;
            lambda = d(1,1):d(1,3):d(1,2);
            theta = d(2,1):d(2,3):d(2,2);
            
            
            d = app.UITable_2.Data;
            dat_label = d(:,1);
            N_dat = length(d);
            
            R = R(:,1:length(theta)*N_dat);
            
            R = reshape(R,length(lambda),[],N_dat);
%             R = R(:,1:length(theta),:);
            
            %% Collect outputs
%             cla(app.UIAxes,'reset')
            tmp = reshape(R,length(lambda),[]);
            imagesc(app.UIAxes,1:size(tmp,2),lambda,tmp)
            
%             app.UIAxes.XLim = [min(theta) max(theta)];
            app.UIAxes.YLim = [min(lambda) max(lambda)];
            app.UIAxes.XLim = [0.5 size(tmp,2)+0.5];
%             app.UIAxes.YLim = [0.5 size(tmp,1)+0.5];
            xlabel(app.UIAxes,'$\theta$')
            ylabel(app.UIAxes,'$\lambda$')
            title(app.UIAxes,'$R$')
            colorbar(app.UIAxes)
            drawnow

            data = [];
            data.R = R;
            data.theta = theta;
            data.lambda = lambda;
            data.type = dat_label;
            
            if exist(app.OutputdataTextArea.Value{1},'file')
                save(app.OutputdataTextArea.Value{1},'-struct','data','-append')
            else
                save(app.OutputdataTextArea.Value{1},'-struct','data')
            end
            
            saveappfig(app,'Load raw data')
        end

        % Button pushed function: OutputfolderButton
        function OutputfolderButtonPushed(app, event)
            s = app.OutputdataTextArea.Value{1};
            [FILEPATH,NAME,EXT] = fileparts(s);
            
            datapath = uigetdir(FILEPATH);
            if ischar(datapath)
                app.OutputdataTextArea.Value = [datapath '\' NAME '.' EXT];
            else
                app.OutputdataTextArea.Value = [FILEPATH '\ZPCapp_workspace.mat'];
            end
        end

        % Button pushed function: OutputfileButton
        function OutputfileButtonPushed(app, event)
            s = app.OutputdataTextArea.Value{1};
            [FILEPATH,NAME,EXT] = fileparts(s);
            
            [filename,FILEPATH] = uigetfile([FILEPATH '\' '*.mat']);
            app.OutputdataTextArea.Value = [FILEPATH '\' filename];
        end

        % Button pushed function: LoadButton_4
        function LoadButton_4Pushed3(app, event)
            s = app.OutputdataTextArea.Value{1};
            [FILEPATH,NAME,EXT] = fileparts(s);
            
            [filename,FILEPATH] = uigetfile([FILEPATH '\' '*.mat']);
            
            tmp = load([FILEPATH '\' filename]);
            if exist(app.OutputdataTextArea.Value{1},'file')
                save(app.OutputdataTextArea.Value{1},'-struct','tmp','-append')
            else
                save(app.OutputdataTextArea.Value{1},'-struct','tmp')
            end
        end

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            s = app.ReflectivityDataEditField.Value;
            [FILEPATH,NAME,EXT] = fileparts(s);
            
            datapath = uigetdir(FILEPATH);
            if ischar(datapath)
                app.ReflectivityDataEditField.Value = [datapath '\' NAME '.' EXT];
            else
                app.ReflectivityDataEditField.Value = [FILEPATH '\Reflectivityapp_workspace.mat'];
            end
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            s = app.ReflectivityDataEditField.Value;
            [FILEPATH,NAME,EXT] = fileparts(s);
            
            [filename,FILEPATH] = uigetfile([FILEPATH '\' '*.dat']);
            app.ReflectivityDataEditField.Value = [FILEPATH '\' filename];
        end

        % Button pushed function: MLButton
        function MLButtonPushed(app, event)
            app.UITable_2.Data{end+1,1} = 'Multilayer';
        end

        % Button pushed function: AbsButton
        function AbsButtonPushed(app, event)
            app.UITable_2.Data{end+1,1} = 'Absorber';
        end

        % Button pushed function: RmButton
        function RmButtonPushed(app, event)
            app.UITable_2.Data(end,:) = [];
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            s = app.OutputdataTextArea.Value{1};
            [FILEPATH,NAME,EXT] = fileparts(s);
            
            [filename,FILEPATH] = uigetfile([FILEPATH '\' '*.xlsx']);
            d = importdata([FILEPATH '\' filename]);
            n = d.data;
            d = d.textdata(2:end,:);
            
            ii = 1:size(d,1);
%             ii = strcmp(d(:,3),'Multilayer');
            
            app.UITable2.Data = d(ii,:);
            
%             app.UITable2.Data(:,1:3) = d(ii,:);
            app.UITable2.Data(:,4:6) = num2cell(n(ii,:));
            
            saveappfig(app,'Load film model')
        end

        % Callback function
        function LoadButton_4Pushed(app, event)
            s = app.OutputdataTextArea.Value{1};
            [FILEPATH,NAME,EXT] = fileparts(s);
            
            [filename,FILEPATH] = uigetfile([FILEPATH '\' '*.xlsx']);
            d = importdata([FILEPATH '\' filename]);
            n = d.data;
            d = d.textdata(2:end,:);
            
            ii = ~strcmp(d(:,3),'Multilayer');
            
            app.UITable2_5.Data = d(ii,:);
            
%             app.UITable2.Data(:,1:3) = d(ii,:);
            app.UITable2_5.Data(:,4:6) = num2cell(n(ii,:));
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            s = app.OutputdataTextArea.Value{1};
            [FILEPATH,NAME,EXT] = fileparts(s);
            
            [filename,FILEPATH] = uigetfile([FILEPATH '\' '*.mat']);
            
            d = load([FILEPATH '\' filename]);
            if exist(s,'file')
                tmp = load(s);
                if ~isfield(tmp,'data_cell')
                    data_cell = {};
                else
                    data_cell = tmp.data_cell;
                end
            else
                data_cell = {};
            end
            data_cell_new = cell(length(d.type),1);
            for i = 1:length(data_cell_new)
                data_cell_new{i}.R = d.R(:,:,i);
                data_cell_new{i}.theta = d.theta;
                data_cell_new{i}.lambda = d.lambda;
                data_cell_new{i}.type = d.type{i};
                data_cell_new{i}.source = filename;
            end
            data_cell = [data_cell; data_cell_new];
            
            if exist(app.OutputdataTextArea.Value{1},'file')
                save(app.OutputdataTextArea.Value{1},'data_cell','-append')
            else
                save(app.OutputdataTextArea.Value{1},'data_cell')
            end
            
            updateDataSelector(app)
            
            saveappfig(app,'Load data_multidimensional fit')
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% Data
            s = app.OutputdataTextArea.Value{1};
            if exist(app.OutputdataTextArea.Value{1},'file')
                d = load(s,'data_cell');
                data_cell = d.data_cell;
                
                d = app.UITable2_3.Data;
                
                is_data = cell2mat(d(:,2));
                
                ind_ML = find(and(is_data,strcmp(d(:,1),'Multilayer')),1);
                if isempty(ind_ML)
                    return
                end
                ind_Abs = find(and(is_data,strcmp(d(:,1),'Absorber')),1);
                if isempty(ind_Abs)
                    return
                end
                
                Rml = data_cell{ind_ML}.R;
                theta = data_cell{ind_ML}.theta;
                lambda = data_cell{ind_ML}.lambda;
                Rabs = data_cell{ind_Abs}.R;
            else
                return
            end
            
            %% Illumination conditions
            illum_data = app.UITable5.Data;
            lambda_min = illum_data(1,1);
            lambda_max = illum_data(1,2);
            theta_min = illum_data(2,1);
            theta_max = illum_data(2,2);
            
            lambda(lambda > lambda_max) = [];
            lambda(lambda < lambda_min) = [];
            
            ii_L = ismember( data_cell{ind_ML}.lambda,lambda);
            
            theta(theta > theta_max) = [];
            theta(theta < theta_min) = [];
            
            ii_T = ismember( data_cell{ind_ML}.theta,theta);
            
            Rml = Rml(ii_L,ii_T);
            Rabs = Rabs(ii_L,ii_T);
            
            [tG,lG] = meshgrid(theta,lambda);
            lambdaTheta = [vec(lG),vec(tG)];
            kz = 2*pi*cosd(lambdaTheta(:,2))./lambdaTheta(:,1);
            
            %% Materials set
            d = app.UITable2.Data;
            s0 = d(strcmp(d(:,2),'Concentration'),1);
            s = s0;
            for i = 1:length(s)
                tmp = strsplit(s0{i},'-');
                s{i} = strtrim(tmp{end});
            end
            materials = unique(s);
%             materials = {'Ru';'Si';'Mo';'Ta';'N';'O';'C'};
            [f0f1_elements_r,rho_nom,amu] = getScatFac(materials,lambdaTheta(:,1));
            
            %%
            is_ML = strcmp(d(:,3),'Multilayer');
            is_Abs = strcmp(d(:,3),'Absorber');
            is_Etch = strcmp(d(:,3),'Etch');
            is_thick = strcmp(d(:,2),'Thickness');
            is_rough = strcmp(d(:,2),'Roughness');
            is_comp = strcmp(d(:,2),'Concentration');
            
            
            L = cell2mat(d(:,4));
            nom = cell2mat(d(:,5));
            U = cell2mat(d(:,6));
            
            %% Thickness
            thick_abs = nom(and(is_Abs,is_thick));
            thick_etch = nom(and(is_Etch,is_thick));
            thick_ML = nom(and(is_ML,is_thick));
            
            L_thick_abs = L(and(is_Abs,is_thick));
            L_thick_etch = L(and(is_Etch,is_thick));
            L_thick_ML = L(and(is_ML,is_thick));
            
            U_thick_abs = U(and(is_Abs,is_thick));
            U_thick_etch = U(and(is_Etch,is_thick));
            U_thick_ML = U(and(is_ML,is_thick));
            
            %% Roughness
            rough_abs = nom(and(is_Abs,is_rough));
            rough_etch = nom(and(is_Etch,is_rough));
            rough_ML = nom(and(is_ML,is_rough));
            
            L_rough_abs = L(and(is_Abs,is_rough));
            L_rough_etch = L(and(is_Etch,is_rough));
            L_rough_ML = L(and(is_ML,is_rough));
            
            U_rough_abs = U(and(is_Abs,is_rough));
            U_rough_etch = U(and(is_Etch,is_rough));
            U_rough_ML = U(and(is_ML,is_rough));
            
            %% Composition/concentration
            comp_abs = nom(and(is_Abs,is_comp));
            comp_etch = nom(and(is_Etch,is_comp));
            comp_ML = nom(and(is_ML,is_comp));
            
            L_comp_abs = L(and(is_Abs,is_comp));
            L_comp_etch = L(and(is_Etch,is_comp));
            L_comp_ML = L(and(is_ML,is_comp));
            
            U_comp_abs = U(and(is_Abs,is_comp));
            U_comp_etch = U(and(is_Etch,is_comp));
            U_comp_ML = U(and(is_ML,is_comp));
            
            %% Concatenate variables
            thick = [thick_abs;thick_ML;thick_etch];
            L_thick = [L_thick_abs;L_thick_ML;L_thick_etch];
            U_thick = [U_thick_abs;U_thick_ML;U_thick_etch];
            
            rough = [rough_abs;rough_ML;rough_etch;0;0];
            L_rough = [L_rough_abs;L_rough_ML;L_rough_etch;0;0];
            U_rough = [U_rough_abs;U_rough_ML;U_rough_etch;0;0];
            if ~app.IncludesurfaceroughnessCheckBox.Value
                rough = 0*rough;
                L_rough = 0*L_rough;
                U_rough = 0*U_rough;
            end
            
            composition_abs = zeros(length(thick_abs),length(materials));
            L_composition_abs = composition_abs;
            U_composition_abs = composition_abs;
            ind1 = 0;
            str_prev = '';
            inds = find(and(is_Abs,is_comp));
            is_nonzero_abs = false(length(thick_abs),length(materials));
            for i = 1:length(comp_abs)
                s = d{inds(i),1};
                tmp = strsplit(s,'-');
                tmp = cellfun(@(x) strtrim(x),tmp,'UniformOutput',false);
                
                ind2 = find(strcmp(materials,tmp{2}),1);

                if ~strcmp(tmp{1},str_prev)
                    ind1 = ind1 + 1;
                end
                str_prev = tmp{1};
                
                composition_abs(ind1,ind2) = comp_abs(i);
                L_composition_abs(ind1,ind2) = L_comp_abs(i);
                U_composition_abs(ind1,ind2) = U_comp_abs(i);
                is_nonzero_abs(ind1,ind2) = true;
            end
            
            composition_etch = zeros(length(thick_etch),length(materials));
            L_composition_etch = composition_etch;
            U_composition_etch = composition_etch;
            ind1 = 1;
            str_prev = '';
            inds = find(and(is_Etch,is_comp));
            is_nonzero_etch = false(length(thick_etch),length(materials));
            for i = 1:length(comp_etch)
                s = d{inds(i),1};
                tmp = strsplit(s,'-');
                tmp = cellfun(@(x) strtrim(x),tmp,'UniformOutput',false);
                
                ind2 = find(strcmp(materials,tmp{2}),1);

                if ~strcmp(tmp{1},str_prev)
                    ind1 = ind1 + 1;
                end
                str_prev = tmp{1};
                
                composition_etch(ind1,ind2) = comp_etch(i);
                L_composition_etch(ind1,ind2) = L_comp_etch(i);
                U_composition_etch(ind1,ind2) = U_comp_etch(i);
                is_nonzero_etch(ind1,ind2) = true;
            end
            
            composition_ML = zeros(length(thick_ML),length(materials));
            L_composition_ML = composition_ML;
            U_composition_ML = composition_ML;
            ind1 = 0;
            str_prev = '';
            inds = find(and(is_ML,is_comp));
            is_nonzero_ML = false(length(thick_ML),length(materials));
            for i = 1:length(comp_ML)
                s = d{inds(i),1};
                tmp = strsplit(s,'-');
                tmp = cellfun(@(x) strtrim(x),tmp,'UniformOutput',false);
                
                ind2 = find(strcmp(materials,tmp{2}),1);

                if ~strcmp(tmp{1},str_prev)
                    ind1 = ind1 + 1;
                end
                str_prev = tmp{1};
                
                composition_ML(ind1,ind2) = comp_ML(i);
                L_composition_ML(ind1,ind2) = L_comp_ML(i);
                U_composition_ML(ind1,ind2) = U_comp_ML(i);
                is_nonzero_ML(ind1,ind2) = true;
            end
            
            composition = [composition_abs',composition_ML',composition_etch'];
            L_composition = [L_composition_abs',L_composition_ML',L_composition_etch'];
            U_composition = [U_composition_abs',U_composition_ML',U_composition_etch'];
%             is_nonzero_comp = [is_nonzero_abs',is_nonzero_ML',is_nonzero_etch'];
            
            %% Vectorized parameter model
            ind_struct = [];
            ind_struct.thick = 1:numel(thick);
            ind_struct.rough = ind_struct.thick(end) + (1:numel(rough));
            ind_struct.composition = ind_struct.rough(end) + (1:numel(composition));
            ind_struct.substrate_roughness = ind_struct.composition(end) + 1;
            
            inds_c = reshape(ind_struct.composition,size(composition));
            
            layers_abs = 1:length(thick_abs);
            layers_ML = layers_abs(end) + (1:length(thick_ML));
            layers_etch = layers_ML(end) + (1:length(thick_etch));
            
            inds_ML = [ind_struct.thick(layers_ML),ind_struct.rough(layers_ML),vec(inds_c(:,layers_ML))'];
            inds_abs = [ind_struct.thick(layers_abs),ind_struct.rough(layers_abs),vec(inds_c(:,layers_abs))'];
            inds_etch = [ind_struct.thick(layers_etch),ind_struct.rough(layers_etch),vec(inds_c(:,layers_etch))'];
%             inds_nonzero = [ind_struct.thick,ind_struct.rough(1:end-1),ind_struct.composition(is_nonzero_comp),ind_struct.substrate_roughness];
            
            
            if app.RoughnessamplitudecorrectionCheckBox.Value
                substrate_roughness = app.SubstrateRMSnmEditField.Value;
            else
                substrate_roughness = 0;
            end
            
            %%
            optTbl = app.UITable6.Data;
            
            
            x0_MLabsEtch = [thick;rough;vec(composition);substrate_roughness];
            LB = [L_thick;L_rough;vec(L_composition);substrate_roughness];
            UB = [U_thick;U_rough;vec(U_composition);substrate_roughness];
            
            nom_abs_ind = find(~any(optTbl(:,11:12),2),1);
            if isempty(nom_abs_ind)
                x_nomAbs = x0_MLabsEtch;
            end
            
            table = [LB x0_MLabsEtch UB];
            ii = var(table,[],2) >= 1e-12; % Reduce number of variables to be optimized
            
            %% Variable labels
            layer_mat_abs = [d(and(is_thick,is_Abs),1);d(and(is_thick,is_ML),1);d(and(is_thick,is_Etch),1)];
            layer_mat_abs{end+1} = '';
            layer_type = [repmat({'Absorber'},length(layers_abs),1);repmat({'Multilayer'},length(layers_ML),1);repmat({'Etch'},length(layers_etch),1);{''}];
            
            fn = fieldnames(ind_struct);
            var_labels = {'Thickness [nm]','Roughness [nm]','Composition [%]','Substrate roughness [nm]'};
            var_labels1 = {'Thickness','Roughness','Concentration','Substrate roughness'};
            var_strs = cell(ind_struct.(fn{end})(end),1);
            label_cols = cell(ind_struct.(fn{end})(end),3);
            for i = 1:length(fn)
                ind = ind_struct.(fn{i});
                for j = 1:length(ind)
                    label_cols{ind(j),2} = var_labels1{i};
                    switch fn{i}
                        case 'composition'
                            [ind_mat,ind_layer] = ind2sub(size(composition),j);
                            var_strs{ind(j)} = [var_labels{i} ' ' layer_mat_abs{ind_layer} ' - ' materials{ind_mat}];
                            label_cols{ind(j),1} = [layer_mat_abs{ind_layer} ' - ' materials{ind_mat}];
%                             label_cols{ind(j),2} = var_labels1{i};
                            label_cols{ind(j),3} = layer_type{ind_layer};
                        otherwise
                            if strncmp('etch',fn{i},4)
                                var_strs{ind(j)} = var_labels{i};
                            elseif strcmp('scale',fn{i})
                                var_strs{ind(j)} = 'Scale factor';
                            elseif strncmp('corr',fn{i},4)
                                var_strs{ind(j)} = 'Substrate roughness';
                            else
                                var_strs{ind(j)} = [var_labels{i} ' ' layer_mat_abs{ismember(1:length(layer_mat_abs),j)}];
                                
                                ind_layer = j;
                                label_cols{ind(j),1} = layer_mat_abs{ind_layer};
                                label_cols{ind(j),3} = layer_type{ind_layer};
                            end
                    end
                    
                end
            end
            %% Define Fresnel function
            p = app.PolarizationEditField.Value;
            if p == 0
                pol = 's';
            elseif p == 1
                pol = 'p';
            else
                pol = p; % Need to change, to calculate mixed polarization state
            end
            
            N_ML = app.N_MLEditField.Value;
            n_etch = length(thick_abs)-1;
            n_cap = length(thick_abs);
            n_per = length(thick_ML);
            n_contam = 1;
            
            fFres_pol = @(x,pol) calcMLAbsEtchFresnel(N_ML,n_cap,n_per,n_contam,n_etch,x(ind_struct.thick,:),amu,rho_nom,x(ind_struct.rough,:),f0f1_elements_r,reshape(x(ind_struct.composition,:),[size(composition) size(x,2)]),lambdaTheta,pol,1,x(ind_struct.substrate_roughness,:));
            fFres = @(x) fFres_pol(x,pol);
            if isnumeric(pol)
                fFresI = @(x) calcR_pol(@(x,p) fFres_pol(x,p),x,pol);
            else
                fFresI = @(x) fFres(x);
            end
            r_meas_abs = real(sqrt(Rabs));
            r_meas_ml = real(sqrt(Rml));
            
            weighted_fit = app.WeightabsorberCheckBox.Value;
            
            metric = 'MSE';
            
            w = [ones(size(kz))/norm(r_meas_abs(:)); ones(size(kz))/norm(r_meas_ml(:))]; % Weighted fit based on 1/norm of absorber and multilayer
            if ~weighted_fit
                w = ones(size(w)); % Non-weighted fit: uniform weighting for all absorber and multilayer measurements
            end
%             wabs = w(1:end/2);
%             wml = w(end/2+1:end);
            
            f_c = @(x) loss_fun({vec(r_meas_abs),vec(r_meas_ml)},@(x)fFresI(x),x,w,metric);
%             r = fFres(x0_MLabsEtch);
%             ncycles_r = app.CoordinatecyclesEditField.Value;
%             niter_r = app.IterationsGSSEditField.Value;
%             alpha_r = app.alphaGSSEditField.Value;
%             step0_r = app.InitstepEditField.Value;
%             fac_r = app.ShrinkfactorEditField.Value;
            
            x_fit_full = x0_MLabsEtch;
            tstart = tic;
            costs = [];
            for iter_seq = 1:size(optTbl,1)
                %% Define one step in optimization sequence
%                    Cycles,Iter,Shrink,Alpha,Step,Opt thick,Opt rough,Opt comp,Opt ML,Opt abs,Opt etch
                tblRow = optTbl(iter_seq,:);
                f_c_tmp = @(x) f_c(x);
                ii1 = ii;
                ncycles_r = tblRow(1);
                niter_r = tblRow(2);
                fac_r = tblRow(3);
                alpha_r = tblRow(4);
                step0_r = tblRow(5);
                prior_strength = tblRow(6);
                
                opt_thick = tblRow(7);
                if ~opt_thick
                    ii1(ind_struct.thick) = false;
                end
                opt_rough = tblRow(8);
                if ~opt_rough
                    ii1(ind_struct.rough) = false;
                end
                opt_comp = tblRow(9);
                if ~opt_comp
                    ii1(ind_struct.composition) = false;
                end
                opt_ML = tblRow(10);
                if ~opt_ML
                    ii1(inds_ML) = false;
                end
                opt_abs = tblRow(11);
                if ~opt_abs
                    ii1(inds_abs) = false;
                end
                opt_etch = tblRow(12);
                if ~opt_etch
                    ii1(inds_etch) = false;
                end
                
                inds_tmp = find(ii1);
                %% Run current step in optimization sequence
                for iter = 1:ncycles_r
                    %% Algorithm: golden-section line-search/cyclic coordinate descent 
                    [x_fit,fx] = gss_coord_descentEWMA(x_fit_full(inds_tmp),@(x)f_c_tmp(f_ii(x,x_fit_full,inds_tmp)),LB(inds_tmp),UB(inds_tmp),1,niter_r,1,alpha_r,step0_r*fac_r^(iter-1));
                    x_fit_full(inds_tmp) = x_fit;
                    %% Apply prior knowledge (shrink back to initialization -> proximal operator of minimizing L2 distance from init, maximum likelihood for a Gaussian prior on each variable)
                    prior_strength_tmp = prior_strength*fac_r^(iter-1);
                    x_fit_full(inds_tmp) = x_fit_full(inds_tmp)*(1 - prior_strength_tmp) + x0_MLabsEtch(inds_tmp)*prior_strength_tmp;
                    %% Plot after update
                    x = x_fit_full;
%                     rML_meas = r_meas_ml;%real(sqrt(RML));
%                     rML_meas = reshape(rML_meas,length(kz),[]);
%                     
%                     rAbs_meas = r_meas_abs;%real(sqrt(RAbs));
%                     rAbs_meas = reshape(rAbs_meas,length(kz),[]);
                    
                    costs(end+1) = fx;
                    
                    [rAbs,rML] = fFresI(x);
                    [~,inds] = sort(kz);
%                     subplot(app.UIAxes2,121)
                    plot(app.UIAxes2,kz(inds),abs(rML(inds)),kz(inds),r_meas_ml(inds))
                    title(app.UIAxes2,[num2str(iter_seq) ', ' num2str(iter) ' Multilayer'])
                    xlabel(app.UIAxes2,'$k_z$')
                    ylabel(app.UIAxes2,'$|r|$')
                    app.UIAxes2.XLim = [min(kz),max(kz)];
                    
                    plot(app.UIAxes2_2,kz(inds),abs(rAbs(inds)),kz(inds),r_meas_abs(inds))
                    title(app.UIAxes2_2,[num2str(iter_seq) ', ' num2str(iter) ' Absorber'])
                    xlabel(app.UIAxes2_2,'$k_z$')
                    ylabel(app.UIAxes2_2,'$|r|$')
                    app.UIAxes2_2.XLim = [min(kz),max(kz)];
                    
                    semilogy(app.UIAxes2_3,costs,'.-')
                    xlabel(app.UIAxes2_3,'Iterations')
                    ylabel(app.UIAxes2_3,'MSE')
                    title(app.UIAxes2_3,'')
                    
                    xnorm = ([x0_MLabsEtch x_fit_full] - LB)./(UB - LB);
                    xnorm = xnorm(inds_tmp,:);
                    barh(app.UIAxes2_4,xnorm)
                    title(app.UIAxes2_4,'')
                    ylabel(app.UIAxes2_4,'')
                    xlabel(app.UIAxes2_4,'')
                    app.UIAxes2_4.YTick = 1:length(inds_tmp);
                    app.UIAxes2_4.YTickLabel = var_strs(inds_tmp);
                    app.UIAxes2_4.TickLabelInterpreter = 'latex';
                    app.UIAxes2_4.XLim = [0 1];
                    
                    %%
                    tstop = toc(tstart);
                    app.TextArea.Value{end+1} = [num2str(iter_seq) ',  ' num2str(iter) '   MSE: ' num2str(fx,4) '  ' 'Ellapsed ' num2str(round(tstop/60,2)) ' min'];
                    
                    drawnow()
%                     fprintf()
%                     fprintf([])
                end
                if ~isempty(nom_abs_ind)
                    if iter_seq == nom_abs_ind
                        x_nomAbs = x_fit_full;
                    end
                end
            end
            s = app.OutputdataTextArea.Value{1};
            [FILEPATH,NAME,EXT] = fileparts(s);
            filename = 'Film model_fit.xlsx';
            tbl0 = [LB x0_MLabsEtch x_fit_full UB];
            table = [label_cols, num2cell(tbl0)];
            col_names = {'Layer','Parameter','Type','Min','Nom','Fit','Max'};
            writecell([col_names;table],[FILEPATH '\' filename])
            
            app.UITable2_4.Data = table(ii,:);
            drawnow()
            
            model = [];
            model.data = [r_meas_abs(:),r_meas_ml(:)];
            model.ind_struct = ind_struct;
            model.x0 = x0_MLabsEtch;
            model.x_nomAbs = x_nomAbs;
            model.x = x_fit_full;
            model.fFres_pol = fFres_pol;
            model.fFresI = fFresI;
            model.fFres = fFres;
            model.f_c = f_c;
            model.N_ML = N_ML;
            model.n_cap = n_cap;
            model.n_per = n_per;
            model.n_contam = n_contam;
            model.n_etch = n_etch;
            model.optTbl = optTbl;
            model.lambdaTheta = lambdaTheta;
            model.pol = pol;
            model.f0f1_elements_r = f0f1_elements_r;
            model.amu = amu;
            model.rho_nom = rho_nom;
            model.materials = materials;
            
            if exist(app.OutputdataTextArea.Value{1},'file')
                save(app.OutputdataTextArea.Value{1},'table','model','-append')
            else
                save(app.OutputdataTextArea.Value{1},'table','model')
            end
            
            updateReflectancePlots(app)
            drawnow()
            
            saveappfig(app,'Multidimensional fit')
        end

        % Button pushed function: RemovestepButton
        function RemovestepButtonPushed(app, event)
            if size(app.UITable6.Data,1) > 0
                app.UITable6.Data(end,:) = [];
            end
        end

        % Button pushed function: AddstepButton
        function AddstepButtonPushed(app, event)
            if size(app.UITable6.Data,1) > 0
                app.UITable6.Data(end+1,:) = app.UITable6.Data(end,:);
            else
                app.UITable6.Data(end+1,:) = [10,10,0.9,0.9,0.1,true,false,false,true,false,false];
            end
        end

        % Callback function
        function LoadButton_4Pushed2(app, event)
            s = app.OutputdataTextArea.Value{1};
            [FILEPATH,NAME,EXT] = fileparts(s);
            
            [filename,FILEPATH] = uigetfile([FILEPATH '\' '*.mat']);
            
            s = [FILEPATH '\' filename];
            
            if exist(s,'file')
                tmp = load(s,'model','model_cell');
            else
                return
            end
            
            if isfield(tmp,'model_cell')
                model_cell = tmp.model_cell;
            else
                model_cell = {};
            end
            
            if isfield(tmp,'model')
                tmp.model.source_file = s;
                model_cell{end+1} = tmp.model;
            end
            
            if isempty(model_cell)
                return
            end
            
            if exist(app.OutputdataTextArea.Value{1},'file')
                save(app.OutputdataTextArea.Value{1},'model_cell','-append')
            else
                save(app.OutputdataTextArea.Value{1},'model_cell')
            end
            
            tf = false(size(model_cell));
            tf(end) = true;
            
            updateReflectanceData(app,tf)
            
            saveappfig(app,'Load fitted model')
        end

        % Selection change function: TabGroup3
        function TabGroup3SelectionChanged(app, event)
            selectedTab = app.TabGroup3.SelectedTab;
            saveappfig(app,['Reflectance analysis_' selectedTab.Title])
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab;
            saveappfig(app,['Multidimensional regression_' selectedTab.Title])
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            saveappfig(app,[selectedTab.Title ' Tab'])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ReflectivitydataTab = uitab(app.TabGroup);
            app.ReflectivitydataTab.Title = 'Reflectivity data';
            app.UIAxes = uiaxes(app.ReflectivitydataTab);
            title(app.UIAxes, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Y', 'Interpreter', 'latex')
            app.ReflectivityDataEditFieldLabel = uilabel(app.ReflectivitydataTab);
            app.ReflectivityDataEditFieldLabel.Text = 'Reflectivity Data';
            app.ReflectivityDataEditField = uieditfield(app.ReflectivitydataTab, 'text');
            app.ReflectivityDataEditField.Value = 'C:\Users\stuar\Documents\Data Files\Scatterometry Data\reflectometer_2019_06_22\Intel_ML_reflectivity.dat';
            app.SelectfolderButton = uibutton(app.ReflectivitydataTab, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.OutputfolderButton = uibutton(app.ReflectivitydataTab, 'push');
            app.OutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputfolderButtonPushed, true);
            app.OutputfolderButton.Text = 'Output folder';
            app.OutputdataTextArea = uitextarea(app.ReflectivitydataTab);
            app.OutputdataTextArea.Value = {'C:\Reflectivityapp\Reflectivityapp_workspace.mat'};
            app.OutputfileButton = uibutton(app.ReflectivitydataTab, 'push');
            app.OutputfileButton.ButtonPushedFcn = createCallbackFcn(app, @OutputfileButtonPushed, true);
            app.OutputfileButton.Text = 'Output file';
            app.LoadButton_4 = uibutton(app.ReflectivitydataTab, 'push');
            app.LoadButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_4Pushed3, true);
            app.LoadButton_4.Text = 'Load';
            app.LoadButton = uibutton(app.ReflectivitydataTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SelectfileButton = uibutton(app.ReflectivitydataTab, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.UITable = uitable(app.ReflectivitydataTab);
            app.UITable.ColumnName = {'Min'; 'Max'; 'Steps'};
            app.UITable.RowName = {'lambda'; 'theta'; ''};
            app.UITable.ColumnEditable = [true true true];
            app.UITable_2 = uitable(app.ReflectivitydataTab);
            app.UITable_2.ColumnName = {'Data Sequence'};
            app.UITable_2.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'};
            app.UITable_2.ColumnEditable = true;
            app.MLButton = uibutton(app.ReflectivitydataTab, 'push');
            app.MLButton.ButtonPushedFcn = createCallbackFcn(app, @MLButtonPushed, true);
            app.MLButton.Text = '+ML';
            app.AbsButton = uibutton(app.ReflectivitydataTab, 'push');
            app.AbsButton.ButtonPushedFcn = createCallbackFcn(app, @AbsButtonPushed, true);
            app.AbsButton.Text = '+Abs';
            app.RmButton = uibutton(app.ReflectivitydataTab, 'push');
            app.RmButton.ButtonPushedFcn = createCallbackFcn(app, @RmButtonPushed, true);
            app.RmButton.Text = 'Rm';
            app.MultidimensionalregressionTab = uitab(app.TabGroup);
            app.MultidimensionalregressionTab.Title = 'Multi-dimensional regression';
            app.TabGroup2 = uitabgroup(app.MultidimensionalregressionTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.FilmparametersTab = uitab(app.TabGroup2);
            app.FilmparametersTab.Title = 'Film parameters';
            app.UITable2 = uitable(app.FilmparametersTab);
            app.UITable2.ColumnName = {'Layer'; 'Parameter'; 'Type'; 'Min'; 'Nominal'; 'Max'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false false false true true true];
            app.N_MLEditFieldLabel = uilabel(app.FilmparametersTab);
            app.N_MLEditFieldLabel.Text = 'N_ML';
            app.N_MLEditField = uieditfield(app.FilmparametersTab, 'numeric');
            app.N_MLEditField.Value = 40;
            app.LayerButton = uibutton(app.FilmparametersTab, 'push');
            app.LayerButton.Text = '+Layer';
            app.RmButton_3 = uibutton(app.FilmparametersTab, 'push');
            app.RmButton_3.Text = 'Rm';
            app.LoadButton_3 = uibutton(app.FilmparametersTab, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.DataselectorTab = uitab(app.TabGroup2);
            app.DataselectorTab.Title = 'Data selector';
            app.UITable2_3 = uitable(app.DataselectorTab);
            app.UITable2_3.ColumnName = {'Type'; 'Use'; 'Source'};
            app.UITable2_3.RowName = {};
            app.UITable2_3.ColumnEditable = [false true false];
            app.LoadButton_2 = uibutton(app.DataselectorTab, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.UITable5 = uitable(app.DataselectorTab);
            app.UITable5.ColumnName = {'Min'; 'Max'};
            app.UITable5.RowName = {'lambda'; 'theta'; ''};
            app.UITable5.ColumnEditable = [true true];
            app.PolarizationEditFieldLabel = uilabel(app.DataselectorTab);
            app.PolarizationEditFieldLabel.Text = 'Polarization';
            app.PolarizationEditField = uieditfield(app.DataselectorTab, 'numeric');
            app.RegressionsettingsTab = uitab(app.TabGroup2);
            app.RegressionsettingsTab.Title = 'Regression settings';
            app.WeightabsorberCheckBox = uicheckbox(app.RegressionsettingsTab);
            app.WeightabsorberCheckBox.Text = 'Weight absorber';
            app.WeightabsorberCheckBox.Value = true;
            app.RoughnessamplitudecorrectionCheckBox = uicheckbox(app.RegressionsettingsTab);
            app.RoughnessamplitudecorrectionCheckBox.Text = 'Roughness amplitude correction';
            app.RoughnessamplitudecorrectionCheckBox.Value = true;
            app.SubstrateRMSnmEditFieldLabel = uilabel(app.RegressionsettingsTab);
            app.SubstrateRMSnmEditFieldLabel.Text = 'Substrate RMS [nm]';
            app.SubstrateRMSnmEditField = uieditfield(app.RegressionsettingsTab, 'numeric');
            app.SubstrateRMSnmEditField.Value = 0.13;
            app.UITable6 = uitable(app.RegressionsettingsTab);
            app.UITable6.ColumnName = {'Cycles'; 'Iter'; 'Shrink'; 'Alpha'; 'Step'; 'Prior'; 'Thick'; 'Rough'; 'Comp'; 'ML'; 'Abs'; 'Etch'};
            app.UITable6.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; ''};
            app.UITable6.ColumnEditable = [true true true true true true true true true true];
            app.AddstepButton = uibutton(app.RegressionsettingsTab, 'push');
            app.AddstepButton.ButtonPushedFcn = createCallbackFcn(app, @AddstepButtonPushed, true);
            app.AddstepButton.Text = 'Add step';
            app.RemovestepButton = uibutton(app.RegressionsettingsTab, 'push');
            app.RemovestepButton.ButtonPushedFcn = createCallbackFcn(app, @RemovestepButtonPushed, true);
            app.RemovestepButton.Text = 'Remove step';
            app.IncludesurfaceroughnessCheckBox = uicheckbox(app.RegressionsettingsTab);
            app.IncludesurfaceroughnessCheckBox.Text = 'Include surface roughness';
            app.IncludesurfaceroughnessCheckBox.Value = true;
            app.FittedparametersTab = uitab(app.TabGroup2);
            app.FittedparametersTab.Title = 'Fitted parameters';
            app.UITable2_4 = uitable(app.FittedparametersTab);
            app.UITable2_4.ColumnName = {'Layer'; 'Parameter'; 'Type'; 'Min'; 'Nominal'; 'Fit'; 'Max'};
            app.UITable2_4.RowName = {};
            app.UIAxes2 = uiaxes(app.MultidimensionalregressionTab);
            title(app.UIAxes2, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes2, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes2, 'Y', 'Interpreter', 'latex')
            app.RunButton = uibutton(app.MultidimensionalregressionTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UIAxes2_2 = uiaxes(app.MultidimensionalregressionTab);
            title(app.UIAxes2_2, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes2_2, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes2_2, 'Y', 'Interpreter', 'latex')
            app.TextArea = uitextarea(app.MultidimensionalregressionTab);
            app.UIAxes2_3 = uiaxes(app.MultidimensionalregressionTab);
            title(app.UIAxes2_3, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes2_3, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes2_3, 'Y', 'Interpreter', 'latex')
            app.UIAxes2_4 = uiaxes(app.MultidimensionalregressionTab);
            title(app.UIAxes2_4, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes2_4, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes2_4, 'Y', 'Interpreter', 'latex')
            app.ReflectanceanalysisTab = uitab(app.TabGroup);
            app.ReflectanceanalysisTab.Title = 'Reflectance analysis';
            app.TabGroup3 = uitabgroup(app.ReflectanceanalysisTab);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup3SelectionChanged, true);
            app.FitTab = uitab(app.TabGroup3);
            app.FitTab.Title = 'Fit';
            app.UIAxes5_6 = uiaxes(app.FitTab);
            title(app.UIAxes5_6, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes5_6, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes5_6, 'Y', 'Interpreter', 'latex')
            app.UIAxes5_7 = uiaxes(app.FitTab);
            title(app.UIAxes5_7, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes5_7, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes5_7, 'Y', 'Interpreter', 'latex')
            app.UIAxes5_8 = uiaxes(app.FitTab);
            title(app.UIAxes5_8, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes5_8, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes5_8, 'Y', 'Interpreter', 'latex')
            app.UIAxes5_9 = uiaxes(app.FitTab);
            title(app.UIAxes5_9, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes5_9, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes5_9, 'Y', 'Interpreter', 'latex')
            app.UIAxes5_10 = uiaxes(app.FitTab);
            title(app.UIAxes5_10, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes5_10, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes5_10, 'Y', 'Interpreter', 'latex')
            app.DecompositionTab = uitab(app.TabGroup3);
            app.DecompositionTab.Title = 'Decomposition';
            app.UIAxes5_11 = uiaxes(app.DecompositionTab);
            title(app.UIAxes5_11, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes5_11, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes5_11, 'Y', 'Interpreter', 'latex')
            app.UIAxes5_12 = uiaxes(app.DecompositionTab);
            title(app.UIAxes5_12, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes5_12, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes5_12, 'Y', 'Interpreter', 'latex')
            app.UIAxes5_13 = uiaxes(app.DecompositionTab);
            title(app.UIAxes5_13, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes5_13, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes5_13, 'Y', 'Interpreter', 'latex')
            app.UIAxes5_14 = uiaxes(app.DecompositionTab);
            title(app.UIAxes5_14, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes5_14, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes5_14, 'Y', 'Interpreter', 'latex')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reflectivityapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end